classdef DSP_Project2_AndreiD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AndreiDivinagracia301360608Label  matlab.ui.control.Label
        WindowSizeKnob                  matlab.ui.control.Knob
        WindowSizeKnobLabel             matlab.ui.control.Label
        ChebyRippleKnob                 matlab.ui.control.Knob
        ChebyRippleKnobLabel            matlab.ui.control.Label
        FilterOrderEditField            matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel       matlab.ui.control.Label
        UpperFrequencyLimitSlider       matlab.ui.control.Slider
        UpperFrequencyLimitSliderLabel  matlab.ui.control.Label
        FilterDesignDropDown            matlab.ui.control.DropDown
        FilterDesignDropDownLabel       matlab.ui.control.Label
        LowerFrequencyLimitSlider       matlab.ui.control.Slider
        LowerFrequencyLimitSliderLabel  matlab.ui.control.Label
        WindowTypeButtonGroup           matlab.ui.container.ButtonGroup
        KaiserButton                    matlab.ui.control.RadioButton
        HammingButton                   matlab.ui.control.RadioButton
        BlackmanHarrisButton            matlab.ui.control.RadioButton
        RectangularButton               matlab.ui.control.RadioButton
        FilterTypeButtonGroup           matlab.ui.container.ButtonGroup
        BandStopFilterButton            matlab.ui.control.RadioButton
        BandPassFilterButton            matlab.ui.control.RadioButton
        HighPassFilterButton            matlab.ui.control.RadioButton
        LowPassFilterButton             matlab.ui.control.RadioButton
        ControlPanel                    matlab.ui.container.Panel
        ApplyFilterButton               matlab.ui.control.Button
        PlayUnfilteredButton            matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        DSPProject2AudioFilterLabel     matlab.ui.control.Label
        OutputAxesFFT                   matlab.ui.control.UIAxes
        OutputAxes                      matlab.ui.control.UIAxes
        InputAxesFFT                    matlab.ui.control.UIAxes
        InputAxes                       matlab.ui.control.UIAxes
        FilterAxesPhase                 matlab.ui.control.UIAxes
        FilterAxesMag                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filename;       % Name of input audio file
        input;          % Input audio file
        output;         % Input audio file with the desired effects applied
        Fs;             % Sampling frequency of the input audio file
        Ts;             % Sampling period of the input audio file
        t;              % Time in seconds of the input/output audio file
        t_final;        % Max value of the input audio file
        in_mag_fft;     % Y-axis for input FFT    
        in_freq_fft;    % X-axis for input FFT
        out_mag_fft;    % Y-axis for output FFT    
        out_freq_fft;   % X-axis for output FFT
        b;              % Filter TF Denomenator
        a;              % Filter TF Numerator
        filter_type;
        M;
        Rp;             % Chebychev Ripple
    end
    
    methods (Access = private)
        
        % Disables Control Inputs based on the length of the sound
        function DisableControl(app, sound_length)
            
            % Disable file input fields until sound stops playing
            set(app.LoadButton, 'Enable', 'off');
            set(app.PlayUnfilteredButton, 'Enable', 'off');
            set(app.ApplyFilterButton, 'Enable', 'off');
            if strcmpi(app.LoadButton.Enable, 'off')
                pause(sound_length)
                set(app.LoadButton, 'Enable', 'on');
                set(app.PlayUnfilteredButton, 'Enable', 'on');
                set(app.ApplyFilterButton, 'Enable', 'on');
            end

        end 

        % Fast Fourier Transfrom
        function [mag, fft] = My_FFT(app, y)
            
            sig = y(:,1); % Typically Stereo signal, convert to 1D
            
            % Pad the input array with zeros to make its length a power of 2
            pow2 = nextpow2(length(sig)); % find the next power of 2 larger than the length of y
            pads = zeros(1,2^pow2-length(sig));
            a_in = [sig' pads]; % pad the input array
            
            % Bit reversal
            a_in = bitrevorder(a_in);

            % Butterfly calculation
            L = length(a_in);
            for s = 1:pow2
                N = 2^s; % N-point FFT for each stage of the butterfly in increments of 2^s
            
                W_N = exp((-2*pi*1i)/N); % Calculate Twiddle Factor
            
                % Butterfly stage calculations
                for k = 0:N:L-1
                    W = 1; % reset twiddle factor multiplier for current stage
                    for j = 0:N/2-1
                        runghigh = a_in(j+k+1); % upper rung of each set of rungs
                        runglow = W*a_in(j+k+1+(N/2)); % lower rung scaled by twiddle factor
                        a_in(j+k+1) = runghigh+runglow; % add lower rung to top rung
                        a_in(j+k+1+(N/2)) = runghigh-runglow; % scale lower rung by -1 and add to top rung
                        W = W*W_N; % multiply the previous twiddle factors for current stage
                     end
                end
            end

            % Fold and Normalize FFT
            L = length(a_in);
            P2 = abs(a_in/L); % scale by the number of points
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 4*P1(2:end-1); % normalize magnitude

            % FFT outputs
            mag = P1;
            fft = app.Fs*(0:(L/2))/L; % fold about Nyquist

        end
     
        function cutoff = AnalogFilterType(app)
            
            if app.LowPassFilterButton.Value == 1
                
                % Choose Cutoff Frequency as a fraction of the Nyquist
                F_cut = (app.Fs/2) * (app.LowerFrequencyLimitSlider.Value/100);

                % LPF + Scale Fc by Nyquist
                cutoff = F_cut/(0.5*app.Fs); 
                app.filter_type = "low";

            elseif app.HighPassFilterButton.Value == 1
                
                % Choose Cutoff Frequency as a fraction of the Nyquist
                F_cut = (app.Fs/2) * (app.UpperFrequencyLimitSlider.Value/100);
                
                % HPF + Scale Fc by Nyquist
                cutoff = F_cut/(0.5*app.Fs);
                app.filter_type = "high";
                
            elseif app.BandPassFilterButton.Value == 1
                
                % Choose Cutoff Frequency as a fraction of the Nyquist
                F_lo = (app.Fs/2) * (app.LowerFrequencyLimitSlider.Value/110);
                F_hi = (app.Fs/2) - (app.Fs/2)*(app.UpperFrequencyLimitSlider.Value/110);
                
                % BPF + Scale Fc by Nyquist
                cutoff = [F_lo, F_hi]/(0.5*app.Fs);
                app.filter_type = 'bandpass';

            elseif app.BandStopFilterButton.Value == 1
                
                % Choose Cutoff Frequency as a fraction of the Nyquist
                F_lo = (app.Fs/2) * (app.LowerFrequencyLimitSlider.Value/110);
                F_hi = (app.Fs/2) - (app.Fs/2)*(app.UpperFrequencyLimitSlider.Value/110);
                
                % BPF + Scale Fc by Nyquist
                cutoff = [F_lo, F_hi]/(0.5*app.Fs);
                app.filter_type = 'stop';

            end

        end
   
        function DigitalSincFilterType(app)
        
            %%% LPF Section
            % Choose Cutoff Frequency as a fraction of the Nyquist
            F_lo = (app.Fs/2) * (app.LowerFrequencyLimitSlider.Value/100);
            % Generate Window LPF
            M_array = -(app.M/2):(app.M/2);
            Wn = F_lo/(0.5*app.Fs);
            % Create Sinc
            app.b = Wn*sinc(Wn*M_array);
            app.a = [1 zeros(1,app.M)];

            if app.HighPassFilterButton.Value == 1
                % Generate New LPF from proper sliders
                F_lo = (app.Fs/2) * (app.UpperFrequencyLimitSlider.Value/100);
                Wn = F_lo/(0.5*app.Fs);
                app.b = Wn*sinc(Wn*M_array);                

                % convert LPF to HPF
                app.b = -app.b;
                app.b((app.M/2)+1) = app.b((app.M/2)+1)+1;  
                app.filter_type = "high";
                
            elseif app.BandPassFilterButton.Value == 1
                % Generate New LPF from proper sliders (upper band limit)
                F_hi = (app.Fs/2) * (app.UpperFrequencyLimitSlider.Value/100);
                Wn = F_hi/(0.5*app.Fs);
                b_high = Wn*sinc(Wn*M_array);
                
                % convert LPF to HPF (lower band limit)
                b_low = -app.b;
                b_low((app.M/2)+1) = b_low((app.M/2)+1)+1;
            
                % Convolve LPF w/ HPF
                app.b = conv(b_low, b_high);
                app.filter_type = 'bandpass';

            elseif app.BandStopFilterButton.Value == 1
                % Use generated LPF (set as higher limit)
                F_lo = (app.Fs/2) * (app.LowerFrequencyLimitSlider.Value/100);
                Wn = F_lo/(0.5*app.Fs);
                % Create Sinc
                b_low = Wn*sinc(Wn*M_array);

                % Create a new LPF and convert it to a HPF
                F_hi = (app.Fs/2) * (app.UpperFrequencyLimitSlider.Value/100);
                Wn = F_hi/(0.5*app.Fs);
                b_high = Wn*sinc(Wn*M_array);
                b_high = -b_high;
                b_high((app.M/2)+1) = b_high((app.M/2)+1)+1;

                % Convolve LPF w/ HPF
                app.b = conv(b_low, b_high);
                app.filter_type = 'stop';

            end

        end
        
        function DigitalChevFilterType(app)
           
            %%% LPF Section
            % Choose Cutoff Frequency as a fraction of the Nyquist
            F_lo = (app.Fs/2) * (app.LowerFrequencyLimitSlider.Value/100);
            % Generate Window LPF
            M_array = -(app.M/2):(app.M/2);
            Wn = F_lo/(0.5*app.Fs);
            % Create chebwindow
            app.b = fir1(app.M, Wn, chebwin(app.M+1, app.Rp));
            app.a = [1 zeros(1,app.M)];

            if app.HighPassFilterButton.Value == 1
                % Generate New LPF from proper sliders
                F_lo = (app.Fs/2) * (app.UpperFrequencyLimitSlider.Value/100);
                Wn = F_lo/(0.5*app.Fs);
                app.b = fir1(app.M, Wn, chebwin(app.M+1, app.Rp));                

                % convert LPF to HPF
                app.b = -app.b;
                app.b((app.M/2)+1) = app.b((app.M/2)+1)+1;  
                app.filter_type = "high";
                
            elseif app.BandPassFilterButton.Value == 1
                % Generate New LPF from proper sliders (upper band limit)
                F_hi = (app.Fs/2) * (app.UpperFrequencyLimitSlider.Value/100);
                Wn = F_hi/(0.5*app.Fs);
                b_high = fir1(app.M, Wn, chebwin(app.M+1, app.Rp));
                
                % convert LPF to HPF (lower band limit)
                b_low = -app.b;
                b_low((app.M/2)+1) = b_low((app.M/2)+1)+1;
            
                % Convolve LPF w/ HPF
                app.b = conv(b_low, b_high);
                app.filter_type = 'bandpass';

            elseif app.BandStopFilterButton.Value == 1
                % Use generated LPF (set as higher limit)
                F_lo = (app.Fs/2) * (app.LowerFrequencyLimitSlider.Value/100);
                Wn = F_lo/(0.5*app.Fs);
                % Create Sinc
                b_low = fir1(app.M, Wn, chebwin(app.M+1, app.Rp));

                % Create a new LPF and convert it to a HPF
                F_hi = (app.Fs/2) * (app.UpperFrequencyLimitSlider.Value/100);
                Wn = F_hi/(0.5*app.Fs);
                b_high = fir1(app.M, Wn, chebwin(app.M+1, app.Rp));
                b_high = -b_high;
                b_high((app.M/2)+1) = b_high((app.M/2)+1)+1;

                % Convolve LPF w/ HPF
                app.b = conv(b_low, b_high);
                app.filter_type = 'stop';

            end

        end
        
        function DigitalWindow(app)

            % ============== Define Window Type ==============
            if app.RectangularButton.Value == 1
                app.b = app.b;

            elseif app.BlackmanHarrisButton.Value == 1
                app.b = app.b.*blackman(app.M+1)';

            elseif app.HammingButton.Value == 1
                app.b = app.b.*hamming(app.M+1)';

            elseif app.KaiserButton.Value == 1
                app.b = app.b.*kaiser(app.M+1)';

            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
            % STARTUP FUNCTION 
            % STARTUP FUNCTION 
            % STARTUP FUNCTION 
            % STARTUP FUNCTION 
            % STARTUP FUNCTION 
            % STARTUP FUNCTION 

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Store input file and setup file properties
            [app.filename,PathName,FilterIndex] = uigetfile('.wav');
            if isnumeric(app.filename)
                return;    %user canceled
            end
            [app.input,app.Fs] = audioread(app.filename);
            app.Ts = 1/app.Fs;
            app.t_final = length(app.input)/app.Fs;
            app.t = (0:app.Ts:app.t_final-app.Ts)';

            % Note: 1xN = Mono, 2xN = Stereo
            % If input is mono, make it stereo
            if isvector(app.input)
                app.input = [app.input app.input];
            end

            % Plot Signals
            plot(app.InputAxes, app.input);                                 % Plot Raw Input
            [app.in_mag_fft, app.in_freq_fft] = My_FFT(app, app.input);     % Call FFT
            plot(app.InputAxesFFT, app.in_freq_fft, app.in_mag_fft)         % Plot FFT 
            
            % Set the altered output sound file to input (temporary)
            app.output = app.input;

            % Dont input on file
            app.EditField.Value = app.filename;
           
            % Play input signal
            sound(app.input,app.Fs);
            
            % Disables Control Inputs based on the length of the sound
            DisableControl(app, app.t_final);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                        
            [file,path] = uiputfile('*.wav', 'Save Wav File');
            if isnumeric(file)
                return;    %user canceled
            end

            % Check if the user didn put anything
            if isempty(file) == 1
                saveerror = errordlg('Enter File Name','Save Error');
            else
                filename = fullfile(path, file);
                audiowrite(filename, app.output, app.Fs);
            end

        end

        % Button pushed function: PlayUnfilteredButton
        function PlayUnfilteredButtonPushed(app, event)
            
            % Play Altered Signal and Output on Graph
            % Note: Output one row only on a stereo signal
            sound(app.input, app.Fs)
            plot(app.InputAxes, app.input)

            % Disables Control Inputs based on the length of the sound
            DisableControl(app, app.t_final);

        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            
            app.output = app.input; % Temporary Line

            % Filter Constants
            app.M = round(app.WindowSizeKnob.Value);       
            app.M = 2*ceil(app.M/2);                    % Generate Window
            order = app.FilterOrderEditField.Value;     % TF Order
            app.Rp = app.ChebyRippleKnob.Value;          % for Chebyshev (Analog)
            
            
            % ============== Choose Filter Design ==============
            switch app.FilterDesignDropDown.Value
                case 'Butterworth (Analog)'
                    % Choose between Low, High, Band, Stop
                    Wn = AnalogFilterType(app); 
                    % Apply Filter
                    [app.b, app.a] = butter(order, Wn, app.filter_type);

                case 'Chebyshev (Analog)'
                    % Choose between Low, High, Band, Stop
                    Wn = AnalogFilterType(app);
                    % Apply Filter
                    [app.b, app.a] = cheby1(order, app.Rp, Wn, app.filter_type);

                case 'Moving Average (Digital)'                    
                    % Generate Filter
                    app.b = (1/app.M)*ones(1,app.M);
                    app.a = 1;

                case 'Windowed Sinc (Digital)'
                    DigitalSincFilterType(app);
                    DigitalWindow(app)


                case 'Chebyshev (Digital)'
                    DigitalChevFilterType(app);
                    DigitalWindow(app);
            end

            
            % ============== APPLY FILTER ============== 
            app.output = filter(app.b, app.a, app.input);
            
            % Plot Audio Signals
            plot(app.OutputAxes, app.output);                               % Plot Raw Input
            [app.out_mag_fft, app.out_freq_fft] = My_FFT(app, app.output);  % Call FFT
            plot(app.OutputAxesFFT, app.out_freq_fft, app.out_mag_fft);     % Plot FFT 
            
            % Plot Filter Properties
            [h1, w1] = freqz(app.b, app.a, [], app.Fs);
            h_log = 20*log10(h1); 
            plot(app.FilterAxesMag, w1, real(h_log))

            [phi,w2] = phasez(app.b, app.a, [], app.Fs); % Find filter phase
            phi = phi*(180/pi); % Convert to deg
            plot(app.FilterAxesPhase, w2, phi);

            % Play Altered Signal and Output on Graph
            sound(app.output, app.Fs)

            % Disables Control Inputs based on the length of the sound
            DisableControl(app, app.t_final);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilterAxesMag = uiaxes(app.UIFigure);
            title(app.FilterAxesMag, 'Filter Response: Magnitude')
            xlabel(app.FilterAxesMag, 'Frequency (Hz)')
            ylabel(app.FilterAxesMag, 'Magnitude (dB)')
            zlabel(app.FilterAxesMag, 'Z')
            app.FilterAxesMag.XLim = [0 22000];
            app.FilterAxesMag.YLim = [-100 20];
            app.FilterAxesPhase = uiaxes(app.UIFigure);
            title(app.FilterAxesPhase, 'Filter Response: Phase')
            xlabel(app.FilterAxesPhase, 'Frequency (Hz)')
            ylabel(app.FilterAxesPhase, 'Phase (°)')
            zlabel(app.FilterAxesPhase, 'Z')
            app.FilterAxesPhase.YLim = [-100 20];
            app.InputAxes = uiaxes(app.UIFigure);
            title(app.InputAxes, 'Raw Audio')
            xlabel(app.InputAxes, 'Sample (n)')
            zlabel(app.InputAxes, 'Z')
            app.InputAxesFFT = uiaxes(app.UIFigure);
            title(app.InputAxesFFT, 'Raw Audio FFT')
            xlabel(app.InputAxesFFT, 'Sample (n)')
            zlabel(app.InputAxesFFT, 'Z')
            app.OutputAxes = uiaxes(app.UIFigure);
            title(app.OutputAxes, 'Filtered Audio')
            xlabel(app.OutputAxes, 'Sample (n)')
            zlabel(app.OutputAxes, 'Z')
            app.OutputAxesFFT = uiaxes(app.UIFigure);
            title(app.OutputAxesFFT, 'Filtered Audio FFT')
            xlabel(app.OutputAxesFFT, 'Sample (n)')
            zlabel(app.OutputAxesFFT, 'Z')
            app.DSPProject2AudioFilterLabel = uilabel(app.UIFigure);
            app.DSPProject2AudioFilterLabel.Text = 'DSP Project 2 - Audio Filter ';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.LoadButton = uibutton(app.ControlPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.ControlPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.EditField = uieditfield(app.ControlPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Placeholder = 'Input file name';
            app.PlayUnfilteredButton = uibutton(app.ControlPanel, 'push');
            app.PlayUnfilteredButton.ButtonPushedFcn = createCallbackFcn(app, @PlayUnfilteredButtonPushed, true);
            app.PlayUnfilteredButton.Text = 'Play Unfiltered';
            app.ApplyFilterButton = uibutton(app.ControlPanel, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.FilterTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.LowPassFilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.LowPassFilterButton.Text = 'Low Pass Filter';
            app.LowPassFilterButton.Value = true;
            app.HighPassFilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.HighPassFilterButton.Text = 'High Pass Filter';
            app.BandPassFilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.BandPassFilterButton.Text = 'Band Pass Filter';
            app.BandStopFilterButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.BandStopFilterButton.Text = 'Band Stop Filter';
            app.WindowTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.WindowTypeButtonGroup.Title = 'Window Type';
            app.RectangularButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.RectangularButton.Value = true;
            app.BlackmanHarrisButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.BlackmanHarrisButton.Text = 'Blackman-Harris';
            app.HammingButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.KaiserButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.KaiserButton.Text = 'Kaiser';
            app.LowerFrequencyLimitSliderLabel = uilabel(app.UIFigure);
            app.LowerFrequencyLimitSliderLabel.Text = 'Lower Frequency Limit';
            app.LowerFrequencyLimitSlider = uislider(app.UIFigure);
            app.LowerFrequencyLimitSlider.Limits = [5 95];
            app.LowerFrequencyLimitSlider.Value = 5;
            app.FilterDesignDropDownLabel = uilabel(app.UIFigure);
            app.FilterDesignDropDownLabel.Text = 'Filter Design';
            app.FilterDesignDropDown = uidropdown(app.UIFigure);
            app.FilterDesignDropDown.Items = {'Butterworth (Analog)', 'Chebyshev (Analog)', 'Moving Average (Digital)', 'Windowed Sinc (Digital)', 'Chebyshev (Digital)'};
            app.FilterDesignDropDown.Value = 'Butterworth (Analog)';
            app.UpperFrequencyLimitSliderLabel = uilabel(app.UIFigure);
            app.UpperFrequencyLimitSliderLabel.Text = 'Upper Frequency Limit';
            app.UpperFrequencyLimitSlider = uislider(app.UIFigure);
            app.UpperFrequencyLimitSlider.Limits = [5 95];
            app.UpperFrequencyLimitSlider.Value = 5;
            app.FilterOrderEditFieldLabel = uilabel(app.UIFigure);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilterOrderEditField.Value = 10;
            app.ChebyRippleKnobLabel = uilabel(app.UIFigure);
            app.ChebyRippleKnobLabel.Text = 'Cheby Ripple';
            app.ChebyRippleKnob = uiknob(app.UIFigure, 'continuous');
            app.ChebyRippleKnob.Limits = [1 21];
            app.ChebyRippleKnob.Value = 1;
            app.WindowSizeKnobLabel = uilabel(app.UIFigure);
            app.WindowSizeKnobLabel.Text = 'Window Size';
            app.WindowSizeKnob = uiknob(app.UIFigure, 'continuous');
            app.WindowSizeKnob.Limits = [10 500];
            app.WindowSizeKnob.MajorTicks = [10 100 200 300 400 500];
            app.WindowSizeKnob.MinorTicks = [10 50 100 150 200 250 300 350 400 450 500];
            app.WindowSizeKnob.Value = 10;
            app.AndreiDivinagracia301360608Label = uilabel(app.UIFigure);
            app.AndreiDivinagracia301360608Label.Text = 'Andrei Divinagracia (301360608)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_Project2_AndreiD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end