classdef DSP_project_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AndreiDivinagracia301460608Label  matlab.ui.control.Label
        ControlPanel               matlab.ui.container.Panel
        SaveButton                 matlab.ui.control.Button
        PlayPreviousButton         matlab.ui.control.Button
        ApplyButton                matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        ReverbPanel                matlab.ui.container.Panel
        ReverbCheckBox             matlab.ui.control.CheckBox
        EchoPanel                  matlab.ui.container.Panel
        DelayKnob                  matlab.ui.control.Knob
        DelayKnobLabel             matlab.ui.control.Label
        EchoCheckBox               matlab.ui.control.CheckBox
        RythmicAudioPanningPanel   matlab.ui.container.Panel
        FrequencyKnob              matlab.ui.control.Knob
        FrequencyKnobLabel         matlab.ui.control.Label
        AmpliduteKnob              matlab.ui.control.Knob
        PaningAmpliduteLabel       matlab.ui.control.Label
        HeadphonePanningCheckBox   matlab.ui.control.CheckBox
        UIAxes2                    matlab.ui.control.UIAxes
        DSPProject1DAWPluginLabel  matlab.ui.control.Label
        TremoloPanel               matlab.ui.container.Panel
        DepthofAmplitudeKnob       matlab.ui.control.Knob
        DepthofAmplitudeKnobLabel  matlab.ui.control.Label
        TremoloFrequencyKnob       matlab.ui.control.Knob
        TremoloFrequencyKnobLabel  matlab.ui.control.Label
        DropDown_3                 matlab.ui.control.DropDown
        TremoloCheckBox            matlab.ui.control.CheckBox
        FadeInOutPanel             matlab.ui.container.Panel
        DurationSlider_2           matlab.ui.control.Slider
        DurationSlider             matlab.ui.control.Slider
        DropDown_2                 matlab.ui.control.DropDown
        DropDown                   matlab.ui.control.DropDown
        FadeInCheckBox             matlab.ui.control.CheckBox
        FadeOutCheckBox            matlab.ui.control.CheckBox
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filename;   % Name of input audio file
        input;      % Input audio file
        output;     % Input audio file with the desired effects applied
        Fs;         % Sampling frequency of the input audio file
        Ts;         % Sampling period of the input audio file
        t;          % Time in seconds of the input/output audio file
        t_final;    % Max value of the input audio file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % Store input file and setup file properties
            [app.filename,PathName,FilterIndex] = uigetfile('.wav');
            [app.input,app.Fs] = audioread(app.filename);
            app.Ts = 1/app.Fs;
            app.t_final = length(app.input)/app.Fs;
            app.t = (0:app.Ts:app.t_final-app.Ts)';

            % Note: 1xN = Mono, 2xN = Stereo
            % If input is mono, make it stereo
            if isvector(app.input)
                app.input = [app.input app.input];
            end
           
            % Play and plot input signal
            sound(app.input,app.Fs);
            plot(app.UIAxes, app.t, app.input(:,1))

            % Set the altered output sound file to input
            app.output = app.input;
            
            % Disable the Load and Apply button until sound is finished playing
            set(app.LoadButton, 'Enable', 'off');
            set(app.ApplyButton, 'Enable', 'off');
            set(app.PlayPreviousButton, 'Enable', 'off');
            if strcmpi(app.LoadButton.Enable, 'off')
                pause(app.t_final)
                set(app.LoadButton, 'Enable', 'on');
                set(app.ApplyButton, 'Enable', 'on');
            set(app.PlayPreviousButton, 'Enable', 'on');
            end

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            % Set the altered output sound file to input (COUNTS AS UNDO)
            app.output = app.input;
            
            % Fade in Code -------------------------------------------------
            if app.FadeInCheckBox.Value == 1

                % Define Carrier Signal Properties
                fade_dur = app.DurationSlider.Value * app.t_final / 200;
                fade_sample = fade_dur*app.Fs;
                carrier_fade = linspace(0, 1, fade_sample); carrier_fade = carrier_fade(:);

                % Fade in Linear 
                if strcmpi(app.DropDown.Value, 'Linear')
                    FadeIn = carrier_fade;
                end

                % Fade in Concave Exponential
                if strcmpi(app.DropDown.Value, 'Exponential')
                    FadeIn = carrier_fade.^2;
                end
                
                % Convolute Carrier Signal and Save the Output
                in_fi = app.output(:,1); % Take mono input
                in_fi(1:fade_sample) = FadeIn .* app.output(1:fade_sample,1); % Take mono input
                app.output = in_fi;

            end

            % Fade Out Code -------------------------------------------------
            if app.FadeOutCheckBox.Value == 1
                
                % Define Carrier Signal Properties
                fade_dur = app.DurationSlider_2.Value * app.t_final / 200;
                fade_sample = fade_dur*app.Fs;
                carrier_fade = linspace(0, 1, fade_sample); carrier_fade = carrier_fade(:);
                
                % Fade in Linear
                if strcmpi(app.DropDown_2.Value, 'Linear')
                    FadeOut = 1-carrier_fade;
                end
                
                % Fade Out Convex Exponential
                if strcmpi(app.DropDown_2.Value, 'Exponential')
                    FadeOut = 1-carrier_fade.^2;
                end

                % Apply Carrier Signal and Save the Output
                in_fo = app.output(:,1); % Take mono input
                in_fo(end-fade_sample+1:end) = FadeOut.*app.output(end-fade_sample+1:end,1); % Take mono input
                app.output = in_fo;

            end

            % Tremolo -------------------------------------------------
            if app.TremoloCheckBox.Value == 1
                
                % Define Carrier Signal Properties
                trem_depth = app.DepthofAmplitudeKnob.Value;
                trem_freq  = app.TremoloFrequencyKnob.Value;
                amp = 0.5*(trem_depth/100);
                offset = 1 - amp;
                
                switch app.DropDown_3.Value
                    case 'Sine'
                        tremolo_carrier = sin(2*pi*trem_freq.*app.t);
                    case 'Square'
                        tremolo_carrier = square(2*pi*trem_freq.*app.t);
                    case 'Sawtooth'
                        tremolo_carrier = sawtooth(2*pi*trem_freq.*app.t);
                    case 'Triangle'
                        tremolo_carrier = sawtooth(2*pi*trem_freq.*app.t, 1/2);
                end
                
                % Apply Carrier Signal and Save the Output
                mod = amp.*tremolo_carrier + offset;
                out_trem = app.output .* mod;
                app.output = out_trem;
            end

            % Rythmic Audio Panning -------------------------------------------------
            if app.HeadphonePanningCheckBox.Value == 1
            
                f_pan = app.FrequencyKnob.Value/max(app.t); 
                amp_pan = app.AmpliduteKnob.Value/100;
                sw_pan = amp_pan*(0.5+0.5*sin(2*pi*f_pan*app.t));
                panner_carrier = [sw_pan, 1-sw_pan];
                
                % Store Outputs
                plot(app.UIAxes2, sw_pan);
                in_pan = app.output;
                app.output = in_pan .* panner_carrier;
            
            end

            % Echo -------------------------------------------------
            if app.EchoCheckBox.Value == 1

                timeSec = app.DelayKnob.Value/10;
                d = fix(timeSec * app.Fs);  % round to nearest integer sample
                a = -0.40;                  % Amplitude of each subsequent delay
                
                N = length(app.output);
                output_temp = zeros(N, 2);
                for n = 1:N
                
                    % When the sample number is less than the time delay
                    % Avoid indexing a negative sample number
                    if n < d + 1
                        % output = input
                        output_temp(n,:) = app.output(n,:);
                    
                    else
                        % output = input + delayed version of output
                        % reduce relative amplitude of delay to 3/4
                        output_temp(n,:) = app.output(n,:) + (-a)*output_temp(n-d,:);
                    end
                
                end
                app.output = output_temp;
            end

            % Reverb -------------------------------------------------
            if app.ReverbCheckBox.Value == 1
                
                [Reverb_carrier, F_rev] = audioread('reverbIR.wav');
                % R_carr_new = downsample(Reverb_carrier, 8);

                in_rev = app.output;
                %app.output = in_rev .* R_carr_new;

                in_net = 0.5*(in_rev(:,2) + in_rev(:,1));
                yLeft = conv(in_net, Reverb_carrier(:,1));
                yRight = conv(in_net, Reverb_carrier(:,2));
                app.output = [yLeft, yRight];

            end

            % Play Altered Signal and Output on Graph ---------------------
            % Note: Output one row only on a stereo signal
            sound(app.output, app.Fs)
            plot(app.UIAxes_2, app.output)
            
            % Disable the Apply button until sound is finished
            set(app.LoadButton, 'Enable', 'off');
            set(app.ApplyButton, 'Enable', 'off');
            set(app.PlayPreviousButton, 'Enable', 'off');
            if strcmpi(app.ApplyButton.Enable, 'off')
                pause(app.t_final)
                set(app.LoadButton, 'Enable', 'on');
                set(app.ApplyButton, 'Enable', 'on');
                set(app.PlayPreviousButton, 'Enable', 'on');
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            [file,path] = uiputfile('*.wav', 'Save Wav File');
            if isnumeric(file)
                return;    %user canceled
            end
            filename = fullfile(path, file);
            audiowrite(filename, app.output, app.Fs);

        end

        % Button pushed function: PlayPreviousButton
        function PlayPreviousButtonPushed(app, event)

            % Play Altered Signal and Output on Graph
            % Note: Output one row only on a stereo signal
            sound(app.output, app.Fs)
            plot(app.UIAxes_2, app.output)

            % Disable the Apply button until sound is finished
            set(app.LoadButton, 'Enable', 'off');
            set(app.ApplyButton, 'Enable', 'off');
            set(app.PlayPreviousButton, 'Enable', 'off');
            if strcmpi(app.PlayPreviousButton.Enable, 'off')
                pause(app.t_final)
                set(app.LoadButton, 'Enable', 'on');
                set(app.ApplyButton, 'Enable', 'on');
                set(app.PlayPreviousButton, 'Enable', 'on');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Output Signal')
            xlabel(app.UIAxes_2, 'sample [n]')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YTick = [-1 -0.5 0 0.5 1];
            app.UIAxes_2.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Signal')
            xlabel(app.UIAxes, 'sample [n]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [-1 -0.5 0 0.5 1];
            app.UIAxes.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.FadeInOutPanel = uipanel(app.UIFigure);
            app.FadeInOutPanel.Title = 'Fade In/Out';
            app.FadeOutCheckBox = uicheckbox(app.FadeInOutPanel);
            app.FadeOutCheckBox.Text = 'Fade Out';
            app.FadeInCheckBox = uicheckbox(app.FadeInOutPanel);
            app.FadeInCheckBox.Text = 'Fade In';
            app.DropDown = uidropdown(app.FadeInOutPanel);
            app.DropDown.Items = {'Linear', 'Exponential'};
            app.DropDown.Value = 'Linear';
            app.DropDown_2 = uidropdown(app.FadeInOutPanel);
            app.DropDown_2.Items = {'Linear', 'Exponential'};
            app.DropDown_2.Value = 'Linear';
            app.DurationSlider = uislider(app.FadeInOutPanel);
            app.DurationSlider.MajorTicks = [0 20 40 60 80 100];
            app.DurationSlider_2 = uislider(app.FadeInOutPanel);
            app.DurationSlider_2.MajorTicks = [0 20 40 60 80 100];
            app.TremoloPanel = uipanel(app.UIFigure);
            app.TremoloPanel.Title = 'Tremolo';
            app.TremoloCheckBox = uicheckbox(app.TremoloPanel);
            app.TremoloCheckBox.Text = 'Tremolo';
            app.DropDown_3 = uidropdown(app.TremoloPanel);
            app.DropDown_3.Items = {'Sine', 'Square', 'Sawtooth', 'Triangle'};
            app.DropDown_3.Value = 'Sine';
            app.TremoloFrequencyKnobLabel = uilabel(app.TremoloPanel);
            app.TremoloFrequencyKnobLabel.Text = {'Tremolo'; 'Frequency'};
            app.TremoloFrequencyKnob = uiknob(app.TremoloPanel, 'continuous');
            app.TremoloFrequencyKnob.Limits = [0.1 20];
            app.TremoloFrequencyKnob.MajorTicks = [0.1 4 8 12 16 20];
            app.TremoloFrequencyKnob.MajorTickLabels = {'0.1', '4', '8', '12', '16', '20'};
            app.TremoloFrequencyKnob.MinorTicks = [0.1 2 4 6 8 10 12 14 16 18 20];
            app.TremoloFrequencyKnob.Value = 0.1;
            app.DepthofAmplitudeKnobLabel = uilabel(app.TremoloPanel);
            app.DepthofAmplitudeKnobLabel.Text = {'Depth of'; 'Amplitude'};
            app.DepthofAmplitudeKnob = uiknob(app.TremoloPanel, 'continuous');
            app.DepthofAmplitudeKnob.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.DSPProject1DAWPluginLabel = uilabel(app.UIFigure);
            app.DSPProject1DAWPluginLabel.Text = 'DSP Project 1 - DAW Plugin';
            app.RythmicAudioPanningPanel = uipanel(app.UIFigure);
            app.RythmicAudioPanningPanel.Title = 'Rythmic Audio Panning';
            app.UIAxes2 = uiaxes(app.RythmicAudioPanningPanel);
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [0 0.5 1];
            app.HeadphonePanningCheckBox = uicheckbox(app.RythmicAudioPanningPanel);
            app.HeadphonePanningCheckBox.Text = 'Headphone Panning';
            app.PaningAmpliduteLabel = uilabel(app.RythmicAudioPanningPanel);
            app.PaningAmpliduteLabel.Text = 'Amplidute';
            app.AmpliduteKnob = uiknob(app.RythmicAudioPanningPanel, 'continuous');
            app.AmpliduteKnob.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.FrequencyKnobLabel = uilabel(app.RythmicAudioPanningPanel);
            app.FrequencyKnobLabel.Text = 'Frequency';
            app.FrequencyKnob = uiknob(app.RythmicAudioPanningPanel, 'continuous');
            app.FrequencyKnob.Limits = [0 10];
            app.FrequencyKnob.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.EchoPanel = uipanel(app.UIFigure);
            app.EchoPanel.Title = 'Echo';
            app.EchoCheckBox = uicheckbox(app.EchoPanel);
            app.EchoCheckBox.Text = 'Echo';
            app.DelayKnobLabel = uilabel(app.EchoPanel);
            app.DelayKnobLabel.Text = 'Delay';
            app.DelayKnob = uiknob(app.EchoPanel, 'continuous');
            app.DelayKnob.Limits = [0 10];
            app.DelayKnob.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.ReverbPanel = uipanel(app.UIFigure);
            app.ReverbPanel.Title = 'Reverb';
            app.ReverbCheckBox = uicheckbox(app.ReverbPanel);
            app.ReverbCheckBox.Text = 'Reverb';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.LoadButton = uibutton(app.ControlPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ApplyButton = uibutton(app.ControlPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.PlayPreviousButton = uibutton(app.ControlPanel, 'push');
            app.PlayPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPreviousButtonPushed, true);
            app.PlayPreviousButton.Text = 'Play (Previous)';
            app.SaveButton = uibutton(app.ControlPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AndreiDivinagracia301460608Label = uilabel(app.UIFigure);
            app.AndreiDivinagracia301460608Label.Text = 'Andrei Divinagracia (301460608)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_project_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end